classdef imstack1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        MaxEditFieldLabel  matlab.ui.control.Label
        MaxColor           matlab.ui.control.NumericEditField
        MinEditFieldLabel  matlab.ui.control.Label
        MinColor           matlab.ui.control.NumericEditField
        SliceSliderLabel   matlab.ui.control.Label
        Slice              matlab.ui.control.Slider
        UIAxes             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        InputImage % Input stack of images
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            if isempty(varargin)
                error('No input images')
            elseif length(size(varargin{1})) ==3
                app.InputImage = varargin{1};
            else
                error('Wrong Image Input: data must be 3d image [slice,x,y]')
            end
            
            %data = app.InputImage(:,:,25);
            %imshow(data,[double(app.MinColor) double(app.MaxColor)],'parent',app.UIAxes),colormap(gca,'jet'),colorbar;


        end

        % Value changed function: MaxColor
        function MaxColorValueChanged(app, event)
            value = app.MaxColor.Value;
            
        end

        % Value changed function: MinColor
        function MinColorValueChanged(app, event)
            value = app.MinColor.Value;
            
        end

        % Callback function: Slice, UIFigure
        function SliceValueChanged(app, event)
            event.Key
            value = app.Slice.Value
            
        end

        % Value changed function: Slice
        function SliceValueChanged2(app, event)
            event
            value = app.Slice.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @SliceValueChanged, true);
            app.MaxEditFieldLabel = uilabel(app.UIFigure);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxColor = uieditfield(app.UIFigure, 'numeric');
            app.MaxColor.ValueChangedFcn = createCallbackFcn(app, @MaxColorValueChanged, true);
            app.MaxColor.Value = 1;
            app.MinEditFieldLabel = uilabel(app.UIFigure);
            app.MinEditFieldLabel.Text = 'Min';
            app.MinColor = uieditfield(app.UIFigure, 'numeric');
            app.MinColor.ValueChangedFcn = createCallbackFcn(app, @MinColorValueChanged, true);
            app.SliceSliderLabel = uilabel(app.UIFigure);
            app.SliceSliderLabel.Text = 'Slice';
            app.Slice = uislider(app.UIFigure);
            app.Slice.Limits = [0 50];
            app.Slice.MajorTicks = [];
            app.Slice.ValueChangedFcn = createCallbackFcn(app, @SliceValueChanged2, true);
            app.Slice.ValueChangingFcn = createCallbackFcn(app, @SliceValueChanged, true);
            app.Slice.MinorTicks = [];
            app.Slice.Value = 25;
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = imstack1(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end